classdef ColumnProfiler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ColumnSpinner       matlab.ui.control.Spinner
        ColumnSpinnerLabel  matlab.ui.control.Label
        UWBAxes             matlab.ui.control.UIAxes
        Column_profile      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        UWBdata % e.g. 304 x 256 array of doubles
        column_number % Description
        UWB_gray_image % Description
        UWB_image_handle % Description
        UWB_number_of_columns % Description
    end
    
    methods (Access = private)
        
        function update_views(app)
            plot(app.Column_profile, app.UWBdata(:,app.column_number));
            app.ColumnSpinner.Value = app.column_number;
            app.Column_profile.YLimMode = 'auto';
            assignin('base', 'UWB_best_column', app.column_number);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup_function(app)
            app.UWBdata = evalin('base', 'UWBdata');
            app.UWB_gray_image = mat2gray(app.UWBdata);
            % app.Image.set()
            app.UWB_image_handle = imagesc(app.UWBAxes, app.UWBdata);
            axis(app.UWBAxes,'tight')
%             set(get(app.UWB_image_handle,'Children'),'HitTest','off')
            set(app.UWB_image_handle,'HitTest','off')

            % app.UWB_image_handle.ButtonDownFcn = @app.UWB_imageButtonDown2;
%             dcm = datacursormode(app.UIFigure);
%             dcm.Enable = 'on';
            size_UWBdata = size(app.UWBdata);
            app.UWB_number_of_columns = size_UWBdata(2);
            app.column_number = round(app.UWB_number_of_columns/2);
            app.update_views()
        end

        % Button down function: UWBAxes
        function UWB_imageButtonDown2(app, event)
            disp(event);
            % NOTE: with the axes now holding an image, the x-axis is in
            % pixels and we should not scale by the size of the image array
            intersection_point = event.IntersectionPoint;
            xcoord = intersection_point(1);
%             size_UWBdata = size(app.UWBdata);
%             disp(size_UWBdata, xcoord);
%             app.column_number = ceil(size_UWBdata(2)*xcoord)
            app.column_number = round(xcoord)
            app.update_views();
        end

        % Value changed function: ColumnSpinner
        function ColumnSpinnerValueChanged(app, event)
            value = app.ColumnSpinner.Value;
            disp(value);
            if value < 1
                value = 1
            end
            if value > app.UWB_number_of_columns
                value = app.UWB_number_of_columns
            end
            app.column_number = value;
            app.update_views();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Column_profile = uiaxes(app.UIFigure);
            title(app.Column_profile, 'Column')
            xlabel(app.Column_profile, 'time [sec]')
            ylabel(app.Column_profile, 'UWB')
            app.UWBAxes = uiaxes(app.UIFigure);
            title(app.UWBAxes, 'UWB')
            app.UWBAxes.ButtonDownFcn = createCallbackFcn(app, @UWB_imageButtonDown2, true);
            app.ColumnSpinnerLabel = uilabel(app.UIFigure);
            app.ColumnSpinnerLabel.Text = 'Column';
            app.ColumnSpinner = uispinner(app.UIFigure);
            app.ColumnSpinner.ValueDisplayFormat = '%4d';
            app.ColumnSpinner.ValueChangedFcn = createCallbackFcn(app, @ColumnSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColumnProfiler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup_function)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end